classdef balanceInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        SimulerButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        RinitialiserButton              matlab.ui.control.Button
        SimulationdelabalanceLabel      matlab.ui.control.Label
        MasseSlider                     matlab.ui.control.Slider
        MassechoisieEditFieldLabel      matlab.ui.control.Label
        MassechoisieEditField           matlab.ui.control.NumericEditField
        MasseafficheEditFieldLabel      matlab.ui.control.Label
        MasseafficheEditField           matlab.ui.control.NumericEditField
        SauvegarderEditField            matlab.ui.control.EditField
        SauvegarderButton               matlab.ui.control.Button
        CompterButton                   matlab.ui.control.Button
        TarerButton                     matlab.ui.control.Button
        NombredepicesEditFieldLabel     matlab.ui.control.Label
        NombredepicesEditField          matlab.ui.control.NumericEditField
        Tab2                            matlab.ui.container.Tab
        ParamtresLabel                  matlab.ui.control.Label
        ActionneurLabel                 matlab.ui.control.Label
        ChampmagntiqueEditFieldLabel    matlab.ui.control.Label
        ChampmagntiqueEditField         matlab.ui.control.NumericEditField
        DiamtreEditFieldLabel           matlab.ui.control.Label
        DiamtreEditField                matlab.ui.control.NumericEditField
        NombredetoursEditFieldLabel     matlab.ui.control.Label
        NombredetoursEditField          matlab.ui.control.NumericEditField
        CoefficientdefrottementEditFieldLabel  matlab.ui.control.Label
        CoefficientdefrottementEditField  matlab.ui.control.NumericEditField
        InertieEditFieldLabel           matlab.ui.control.Label
        InertieEditField                matlab.ui.control.NumericEditField
        AmplificateurLabel              matlab.ui.control.Label
        TensiondeoffsetEditFieldLabel   matlab.ui.control.Label
        TensiondeoffsetEditField        matlab.ui.control.NumericEditField
        GaindelampliaudioEditFieldLabel  matlab.ui.control.Label
        GaindelampliaudioEditField      matlab.ui.control.NumericEditField
        ConstantedetempsdelampliaudioEditFieldLabel  matlab.ui.control.Label
        ConstantedetempsdelampliaudioEditField  matlab.ui.control.NumericEditField
        InductancedelabobineEditFieldLabel  matlab.ui.control.Label
        InductancedelabobineEditField   matlab.ui.control.NumericEditField
        RsistancequivalentedepuissanceEditFieldLabel  matlab.ui.control.Label
        RsistancequivalentedepuissanceEditField  matlab.ui.control.NumericEditField
        RsistancedelabobineEditFieldLabel  matlab.ui.control.Label
        RsistancedelabobineEditField    matlab.ui.control.NumericEditField
        ConstantecontrelectromotriceEditFieldLabel  matlab.ui.control.Label
        ConstantecontrelectromotriceEditField  matlab.ui.control.NumericEditField
        GaincouranttensionEditFieldLabel  matlab.ui.control.Label
        GaincouranttensionEditField     matlab.ui.control.NumericEditField
        CourantinitialEditFieldLabel    matlab.ui.control.Label
        CourantinitialEditField         matlab.ui.control.NumericEditField
        CapteurdepositionLabel          matlab.ui.control.Label
        AmplitudedeloscillateurEditFieldLabel  matlab.ui.control.Label
        AmplitudedeloscillateurEditField  matlab.ui.control.NumericEditField
        DistanceentreleslectrodesEditFieldLabel  matlab.ui.control.Label
        DistanceentreleslectrodesEditField  matlab.ui.control.NumericEditField
        Gaindu1erampliEditFieldLabel    matlab.ui.control.Label
        Gaindu1erampliEditField         matlab.ui.control.NumericEditField
        Tensiondcalage1erampliEditFieldLabel  matlab.ui.control.Label
        Tensiondcalage1erampliEditField  matlab.ui.control.NumericEditField
        FrquencedecoupuredufiltreEditFieldLabel  matlab.ui.control.Label
        FrquencedecoupuredufiltreEditField  matlab.ui.control.NumericEditField
        LameLabel                       matlab.ui.control.Label
        fffEditFieldLabel               matlab.ui.control.Label
        fffEditField                    matlab.ui.control.NumericEditField
        gggEditFieldLabel               matlab.ui.control.Label
        gggEditField                    matlab.ui.control.NumericEditField
        hhhEditFieldLabel               matlab.ui.control.Label
        hhhEditField                    matlab.ui.control.NumericEditField
        jjEditFieldLabel                matlab.ui.control.Label
        jjEditField                     matlab.ui.control.NumericEditField
        iiiEditFieldLabel               matlab.ui.control.Label
        iiiEditField                    matlab.ui.control.NumericEditField
        MassechoisiegEditFieldLabel     matlab.ui.control.Label
        MassechoisiegEditField          matlab.ui.control.NumericEditField
        SimulerButton_2                 matlab.ui.control.Button
        ValeurspardfautButton           matlab.ui.control.Button
        MasseaffichegEditFieldLabel     matlab.ui.control.Label
        MasseaffichegEditField          matlab.ui.control.NumericEditField
        Gaindu2eampliEditFieldLabel     matlab.ui.control.Label
        Gaindu2eampliEditField          matlab.ui.control.NumericEditField
        Tensiondedcalagedu2eampliEditFieldLabel  matlab.ui.control.Label
        Tensiondedcalagedu2eampliEditField  matlab.ui.control.NumericEditField
        Rsistance1EditFieldLabel        matlab.ui.control.Label
        Rsistance1EditField             matlab.ui.control.NumericEditField
        paisseurdelalameEditFieldLabel  matlab.ui.control.Label
        paisseurdelalameEditField       matlab.ui.control.NumericEditField
        Rsistance2EditFieldLabel        matlab.ui.control.Label
        Rsistance2EditField             matlab.ui.control.NumericEditField
        Rsistance4EditFieldLabel        matlab.ui.control.Label
        Rsistance4EditField             matlab.ui.control.NumericEditField
        Rsistance3EditFieldLabel        matlab.ui.control.Label
        Rsistance3EditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulerButton, SimulerButton_2
        function SimulerButtonPushed(app, event)
            global simout
            assignin("base", "masse", app.MasseSlider.Value)
           
            assignin("base","B",app.ChampmagntiqueEditField.Value)
            assignin("base","D",app.DiamtreEditField.Value)
            assignin("base","N",app.NombredetoursEditField.Value)
            assignin("base","f",app.CoefficientdefrottementEditField.Value)
            assignin("base","I",app.InertieEditField.Value)
            
            assignin("base","Voffset", app.TensiondeoffsetEditField.Value)
            assignin("base", "Kamp", app.GaindelampliaudioEditField.Value)
            assignin("base","to", app.ConstantedetempsdelampliaudioEditField.Value)
            assignin("base","L", app.InductancedelabobineEditField.Value)
            assignin("base","Req", app.RsistancequivalentedepuissanceEditField.Value)
            assignin("base", "R", app.RsistancedelabobineEditField.Value)
            assignin("base","Ke", app.ConstantecontrelectromotriceEditField.Value)
            assignin("base","Kc", app.GaincouranttensionEditField.Value)
            assignin("base","I0", app.CourantinitialEditField.Value)
            
            assignin("base", "", app.AmplitudedeloscillateurEditField.Value)
            assignin("base", "", app.DistanceentreleslectrodesEditField.Value)
            assignin("base", "", app.paisseurdelalameEditField.Value)
            assignin("base", "", app.Gaindu1erampliEditField.Value)
            assignin("base", "", app.Tensiondcalage1erampliEditField.Value)
            assignin("base", "", app.Rsistance1EditField.Value)
            assignin("base", "", app.Rsistance2EditField.Value)
            assignin("base", "", app.FrquencedecoupuredufiltreEditField.Value)
            assignin("base", "", app.Gaindu2eampliEditField.Value)
            assignin("base", "", app.Tensiondedcalagedu2eampliEditField.Value)
            assignin("base", "", app.Rsistance3EditField.Value)
            assignin("base", "", app.Rsistance4EditField.Value)
            
            simout = sim("balanceCAO.slx").balance
            plot(app.UIAxes, simout.Time, simout.Data)
            app.MasseafficheEditField.Value = simout.Data(end)
            app.MasseaffichegEditField.Value = simout.Data(end)
        end

        % Value changing function: MasseSlider
        function MasseSliderValueChanging(app, event)
            changingValue = event.Value;
            app.MassechoisieEditField.Value = changingValue
            app.MassechoisiegEditField.Value = changingValue
        end

        % Value changed function: MassechoisieEditField
        function MassechoisieEditFieldValueChanged(app, event)
            value = app.MassechoisieEditField.Value;
            app.MassechoisiegEditField.Value = value
            app.MasseSlider.Value = value
        end

        % Button pushed function: RinitialiserButton
        function RinitialiserButtonPushed(app, event)
            app.MasseafficheEditField.Value = 0
            cla(app.UIAxes)
            app.SauvegarderEditField.Value = ""
        end

        % Button pushed function: SauvegarderButton
        function SauvegarderButtonPushed(app, event)
            global simout
            save("Saves\" + app.SauvegarderEditField.Value, "simout")
        end

        % Button pushed function: ValeurspardfautButton
        function ValeurspardfautButtonPushed(app, event)
            app.ChampmagntiqueEditField.Value = 0.1128
            app.DiamtreEditField.Value = 0.0406
            app.NombredetoursEditField.Value = 66
            app.CoefficientdefrottementEditField.Value = 0
            app.InertieEditField.Value = 0
            
            app.TensiondeoffsetEditField.Value = 1.063
            app.GaindelampliaudioEditField.Value = 3.97
            app.ConstantedetempsdelampliaudioEditField.Value = 0.00000462
            app.InductancedelabobineEditField.Value = 0.00095
            app.RsistancequivalentedepuissanceEditField.Value = 1.01
            app.RsistancedelabobineEditField.Value = 1.43
            app.ConstantecontrelectromotriceEditField.Value = 1
            app.GaincouranttensionEditField.Value = 3.01
            app.CourantinitialEditField.Value = 1.64
            
            app.AmplitudedeloscillateurEditField.Value = 0
            app.DistanceentreleslectrodesEditField.Value = 0
            app.paisseurdelalameEditField.Value = 0
            app.Gaindu1erampliEditField.Value = 0
            app.Tensiondcalage1erampliEditField.Value = 0
            app.Rsistance1EditField.Value = 0
            app.Rsistance2EditField.Value = 0
            app.FrquencedecoupuredufiltreEditField.Value = 0
            app.Gaindu2eampliEditField.Value = 0
            app.Tensiondedcalagedu2eampliEditField.Value = 0
            app.Rsistance3EditField.Value = 0
            app.Rsistance4EditField.Value = 0
        end

        % Value changed function: MassechoisiegEditField
        function MassechoisiegEditFieldValueChanged(app, event)
            value = app.MassechoisiegEditField.Value;
            app.MassechoisieEditField.Value = value
            app.MasseSlider.Value = value
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.SimulerButton = uibutton(app.Tab, 'push');
            app.SimulerButton.ButtonPushedFcn = createCallbackFcn(app, @SimulerButtonPushed, true);
            app.SimulerButton.Text = 'Simuler';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Animation')
            xlabel(app.UIAxes, 'temps (s)')
            ylabel(app.UIAxes, 'masse (g)')
            app.RinitialiserButton = uibutton(app.Tab, 'push');
            app.RinitialiserButton.ButtonPushedFcn = createCallbackFcn(app, @RinitialiserButtonPushed, true);
            app.RinitialiserButton.Text = 'Réinitialiser';
            app.SimulationdelabalanceLabel = uilabel(app.Tab);
            app.SimulationdelabalanceLabel.Text = {'Simulation'; 'de la balance'};
            app.MasseSlider = uislider(app.Tab);
            app.MasseSlider.ValueChangingFcn = createCallbackFcn(app, @MasseSliderValueChanging, true);
            app.MassechoisieEditFieldLabel = uilabel(app.Tab);
            app.MassechoisieEditFieldLabel.Text = 'Masse choisie';
            app.MassechoisieEditField = uieditfield(app.Tab, 'numeric');
            app.MassechoisieEditField.ValueChangedFcn = createCallbackFcn(app, @MassechoisieEditFieldValueChanged, true);
            app.MasseafficheEditFieldLabel = uilabel(app.Tab);
            app.MasseafficheEditFieldLabel.Text = 'Masse affichée';
            app.MasseafficheEditField = uieditfield(app.Tab, 'numeric');
            app.SauvegarderEditField = uieditfield(app.Tab, 'text');
            app.SauvegarderButton = uibutton(app.Tab, 'push');
            app.SauvegarderButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderButtonPushed, true);
            app.SauvegarderButton.Text = 'Sauvegarder';
            app.CompterButton = uibutton(app.Tab, 'push');
            app.CompterButton.Text = 'Compter';
            app.TarerButton = uibutton(app.Tab, 'push');
            app.TarerButton.Text = 'Tarer';
            app.NombredepicesEditFieldLabel = uilabel(app.Tab);
            app.NombredepicesEditFieldLabel.Text = 'Nombre de pièces';
            app.NombredepicesEditField = uieditfield(app.Tab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.ParamtresLabel = uilabel(app.Tab2);
            app.ParamtresLabel.Text = 'Paramètres';
            app.ActionneurLabel = uilabel(app.Tab2);
            app.ActionneurLabel.Text = 'Actionneur';
            app.ChampmagntiqueEditFieldLabel = uilabel(app.Tab2);
            app.ChampmagntiqueEditFieldLabel.Text = 'Champ magnétique';
            app.ChampmagntiqueEditField = uieditfield(app.Tab2, 'numeric');
            app.ChampmagntiqueEditField.Value = 0.1128;
            app.DiamtreEditFieldLabel = uilabel(app.Tab2);
            app.DiamtreEditFieldLabel.Text = 'Diamètre';
            app.DiamtreEditField = uieditfield(app.Tab2, 'numeric');
            app.DiamtreEditField.Value = 0.0406;
            app.NombredetoursEditFieldLabel = uilabel(app.Tab2);
            app.NombredetoursEditFieldLabel.Text = 'Nombre de tours';
            app.NombredetoursEditField = uieditfield(app.Tab2, 'numeric');
            app.NombredetoursEditField.Value = 66;
            app.CoefficientdefrottementEditFieldLabel = uilabel(app.Tab2);
            app.CoefficientdefrottementEditFieldLabel.Text = 'Coefficient de frottement';
            app.CoefficientdefrottementEditField = uieditfield(app.Tab2, 'numeric');
            app.InertieEditFieldLabel = uilabel(app.Tab2);
            app.InertieEditFieldLabel.Text = 'Inertie';
            app.InertieEditField = uieditfield(app.Tab2, 'numeric');
            app.AmplificateurLabel = uilabel(app.Tab2);
            app.AmplificateurLabel.Text = 'Amplificateur';
            app.TensiondeoffsetEditFieldLabel = uilabel(app.Tab2);
            app.TensiondeoffsetEditFieldLabel.Text = 'Tension de offset';
            app.TensiondeoffsetEditField = uieditfield(app.Tab2, 'numeric');
            app.TensiondeoffsetEditField.Value = 1.063;
            app.GaindelampliaudioEditFieldLabel = uilabel(app.Tab2);
            app.GaindelampliaudioEditFieldLabel.Text = 'Gain de l''ampli audio';
            app.GaindelampliaudioEditField = uieditfield(app.Tab2, 'numeric');
            app.GaindelampliaudioEditField.Value = 3.97;
            app.ConstantedetempsdelampliaudioEditFieldLabel = uilabel(app.Tab2);
            app.ConstantedetempsdelampliaudioEditFieldLabel.Text = 'Constante de temps de l''ampli audio';
            app.ConstantedetempsdelampliaudioEditField = uieditfield(app.Tab2, 'numeric');
            app.ConstantedetempsdelampliaudioEditField.Value = 4.62e-06;
            app.InductancedelabobineEditFieldLabel = uilabel(app.Tab2);
            app.InductancedelabobineEditFieldLabel.Text = 'Inductance de la bobine';
            app.InductancedelabobineEditField = uieditfield(app.Tab2, 'numeric');
            app.InductancedelabobineEditField.Value = 0.00095;
            app.RsistancequivalentedepuissanceEditFieldLabel = uilabel(app.Tab2);
            app.RsistancequivalentedepuissanceEditFieldLabel.Text = 'Résistance équivalente de puissance';
            app.RsistancequivalentedepuissanceEditField = uieditfield(app.Tab2, 'numeric');
            app.RsistancequivalentedepuissanceEditField.Value = 1.01;
            app.RsistancedelabobineEditFieldLabel = uilabel(app.Tab2);
            app.RsistancedelabobineEditFieldLabel.Text = 'Résistance de la bobine';
            app.RsistancedelabobineEditField = uieditfield(app.Tab2, 'numeric');
            app.RsistancedelabobineEditField.Value = 1.43;
            app.ConstantecontrelectromotriceEditFieldLabel = uilabel(app.Tab2);
            app.ConstantecontrelectromotriceEditFieldLabel.Text = 'Constante contre-électromotrice';
            app.ConstantecontrelectromotriceEditField = uieditfield(app.Tab2, 'numeric');
            app.ConstantecontrelectromotriceEditField.Value = 1;
            app.GaincouranttensionEditFieldLabel = uilabel(app.Tab2);
            app.GaincouranttensionEditFieldLabel.Text = 'Gain courant-tension';
            app.GaincouranttensionEditField = uieditfield(app.Tab2, 'numeric');
            app.GaincouranttensionEditField.Value = 3.01;
            app.CourantinitialEditFieldLabel = uilabel(app.Tab2);
            app.CourantinitialEditFieldLabel.Text = 'Courant initial';
            app.CourantinitialEditField = uieditfield(app.Tab2, 'numeric');
            app.CourantinitialEditField.Value = 1.64;
            app.CapteurdepositionLabel = uilabel(app.Tab2);
            app.CapteurdepositionLabel.Text = 'Capteur de position';
            app.AmplitudedeloscillateurEditFieldLabel = uilabel(app.Tab2);
            app.AmplitudedeloscillateurEditFieldLabel.Text = 'Amplitude de l''oscillateur';
            app.AmplitudedeloscillateurEditField = uieditfield(app.Tab2, 'numeric');
            app.DistanceentreleslectrodesEditFieldLabel = uilabel(app.Tab2);
            app.DistanceentreleslectrodesEditFieldLabel.Text = 'Distance entre les électrodes';
            app.DistanceentreleslectrodesEditField = uieditfield(app.Tab2, 'numeric');
            app.Gaindu1erampliEditFieldLabel = uilabel(app.Tab2);
            app.Gaindu1erampliEditFieldLabel.Text = 'Gain du 1er ampli';
            app.Gaindu1erampliEditField = uieditfield(app.Tab2, 'numeric');
            app.Tensiondcalage1erampliEditFieldLabel = uilabel(app.Tab2);
            app.Tensiondcalage1erampliEditFieldLabel.Text = 'Tension décalage 1er ampli';
            app.Tensiondcalage1erampliEditField = uieditfield(app.Tab2, 'numeric');
            app.FrquencedecoupuredufiltreEditFieldLabel = uilabel(app.Tab2);
            app.FrquencedecoupuredufiltreEditFieldLabel.Text = 'Fréquence de coupure du filtre';
            app.FrquencedecoupuredufiltreEditField = uieditfield(app.Tab2, 'numeric');
            app.LameLabel = uilabel(app.Tab2);
            app.LameLabel.Text = 'Lame';
            app.fffEditFieldLabel = uilabel(app.Tab2);
            app.fffEditFieldLabel.Text = 'fff';
            app.fffEditField = uieditfield(app.Tab2, 'numeric');
            app.gggEditFieldLabel = uilabel(app.Tab2);
            app.gggEditFieldLabel.Text = 'ggg';
            app.gggEditField = uieditfield(app.Tab2, 'numeric');
            app.hhhEditFieldLabel = uilabel(app.Tab2);
            app.hhhEditFieldLabel.Text = 'hhh';
            app.hhhEditField = uieditfield(app.Tab2, 'numeric');
            app.jjEditFieldLabel = uilabel(app.Tab2);
            app.jjEditFieldLabel.Text = 'jj';
            app.jjEditField = uieditfield(app.Tab2, 'numeric');
            app.iiiEditFieldLabel = uilabel(app.Tab2);
            app.iiiEditFieldLabel.Text = 'iii';
            app.iiiEditField = uieditfield(app.Tab2, 'numeric');
            app.MassechoisiegEditFieldLabel = uilabel(app.Tab2);
            app.MassechoisiegEditFieldLabel.Text = 'Masse choisie (g)';
            app.MassechoisiegEditField = uieditfield(app.Tab2, 'numeric');
            app.MassechoisiegEditField.ValueChangedFcn = createCallbackFcn(app, @MassechoisiegEditFieldValueChanged, true);
            app.SimulerButton_2 = uibutton(app.Tab2, 'push');
            app.SimulerButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimulerButtonPushed, true);
            app.SimulerButton_2.Text = 'Simuler';
            app.ValeurspardfautButton = uibutton(app.Tab2, 'push');
            app.ValeurspardfautButton.ButtonPushedFcn = createCallbackFcn(app, @ValeurspardfautButtonPushed, true);
            app.ValeurspardfautButton.Text = 'Valeurs par défaut';
            app.MasseaffichegEditFieldLabel = uilabel(app.Tab2);
            app.MasseaffichegEditFieldLabel.Text = 'Masse affichée (g)';
            app.MasseaffichegEditField = uieditfield(app.Tab2, 'numeric');
            app.Gaindu2eampliEditFieldLabel = uilabel(app.Tab2);
            app.Gaindu2eampliEditFieldLabel.Text = 'Gain du 2e ampli';
            app.Gaindu2eampliEditField = uieditfield(app.Tab2, 'numeric');
            app.Tensiondedcalagedu2eampliEditFieldLabel = uilabel(app.Tab2);
            app.Tensiondedcalagedu2eampliEditFieldLabel.Text = 'Tension de décalage du 2e ampli';
            app.Tensiondedcalagedu2eampliEditField = uieditfield(app.Tab2, 'numeric');
            app.Rsistance1EditFieldLabel = uilabel(app.Tab2);
            app.Rsistance1EditFieldLabel.Text = 'Résistance 1';
            app.Rsistance1EditField = uieditfield(app.Tab2, 'numeric');
            app.paisseurdelalameEditFieldLabel = uilabel(app.Tab2);
            app.paisseurdelalameEditFieldLabel.Text = 'Épaisseur de la lame';
            app.paisseurdelalameEditField = uieditfield(app.Tab2, 'numeric');
            app.Rsistance2EditFieldLabel = uilabel(app.Tab2);
            app.Rsistance2EditFieldLabel.Text = 'Résistance 2';
            app.Rsistance2EditField = uieditfield(app.Tab2, 'numeric');
            app.Rsistance4EditFieldLabel = uilabel(app.Tab2);
            app.Rsistance4EditFieldLabel.Text = 'Résistance 4';
            app.Rsistance4EditField = uieditfield(app.Tab2, 'numeric');
            app.Rsistance3EditFieldLabel = uilabel(app.Tab2);
            app.Rsistance3EditFieldLabel.Text = 'Résistance 3';
            app.Rsistance3EditField = uieditfield(app.Tab2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = balanceInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end